classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        configTab                       matlab.ui.container.Tab
        numberofdaysEditFieldLabel      matlab.ui.control.Label
        numberofdaysEditField           matlab.ui.control.NumericEditField
        CompaniesnamesTextAreaLabel     matlab.ui.control.Label
        CompaniesnamesTextArea          matlab.ui.control.TextArea
        SimulateButton                  matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        DEPTab                          matlab.ui.container.Tab
        DEPCONFIGSLabel                 matlab.ui.control.Label
        vip_countEditFieldLabel         matlab.ui.control.Label
        vip_countEditField              matlab.ui.control.NumericEditField
        propability_nLuggageEditFieldLabel  matlab.ui.control.Label
        propability_nLuggageEditField   matlab.ui.control.NumericEditField
        avg_interArrivalTimeEditFieldLabel  matlab.ui.control.Label
        avg_interArrivalTimeEditField   matlab.ui.control.NumericEditField
        avg_counterServTime_LuggageEditFieldLabel  matlab.ui.control.Label
        avg_counterServTime_LuggageEditField  matlab.ui.control.NumericEditField
        avg_counterServTime_nLuggageEditFieldLabel  matlab.ui.control.Label
        avg_counterServTime_nLuggageEditField  matlab.ui.control.NumericEditField
        LB_securityDelayEditFieldLabel  matlab.ui.control.Label
        LB_securityDelayEditField       matlab.ui.control.NumericEditField
        UB_securityDelayEditFieldLabel  matlab.ui.control.Label
        UB_securityDelayEditField       matlab.ui.control.NumericEditField
        avg_securityServTimeEditFieldLabel  matlab.ui.control.Label
        avg_securityServTimeEditField   matlab.ui.control.NumericEditField
        LB_delayToFirstGateEditFieldLabel  matlab.ui.control.Label
        LB_delayToFirstGateEditField    matlab.ui.control.NumericEditField
        UB_delayToFirstGateEditFieldLabel  matlab.ui.control.Label
        UB_delayToFirstGateEditField    matlab.ui.control.NumericEditField
        nQueues_eno_luggageEditFieldLabel  matlab.ui.control.Label
        nQueues_eno_luggageEditField    matlab.ui.control.NumericEditField
        nQueues_eno_nLuggageEditFieldLabel  matlab.ui.control.Label
        nQueues_eno_nLuggageEditField   matlab.ui.control.NumericEditField
        nQueues_VIPEditFieldLabel       matlab.ui.control.Label
        nQueues_VIPEditField            matlab.ui.control.NumericEditField
        nQueues_securityEditFieldLabel  matlab.ui.control.Label
        nQueues_securityEditField       matlab.ui.control.NumericEditField
        Image_DEP1                      matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        ARRTab                          matlab.ui.container.Tab
        ARRCONFIGSLabel                 matlab.ui.control.Label
        propability_foreignerEditFieldLabel  matlab.ui.control.Label
        propability_foreignerEditField  matlab.ui.control.NumericEditField
        propability_nLuggageEditField_2Label  matlab.ui.control.Label
        propability_nLuggageEditField_2  matlab.ui.control.NumericEditField
        LB_interArrivalTimeEditFieldLabel  matlab.ui.control.Label
        LB_interArrivalTimeEditField    matlab.ui.control.NumericEditField
        UB_interarrivalTimeEditFieldLabel  matlab.ui.control.Label
        UB_interarrivalTimeEditField    matlab.ui.control.NumericEditField
        LB_delayToVisaClrEditFieldLabel  matlab.ui.control.Label
        LB_delayToVisaClrEditField      matlab.ui.control.NumericEditField
        UB_delayToVisaClrEditFieldLabel  matlab.ui.control.Label
        UB_delayToVisaClrEditField      matlab.ui.control.NumericEditField
        avg_visaClrServTimeEditFieldLabel  matlab.ui.control.Label
        avg_visaClrServTimeEditField    matlab.ui.control.NumericEditField
        LB_delayToLuggageAreaEditFieldLabel  matlab.ui.control.Label
        LB_delayToLuggageAreaEditField  matlab.ui.control.NumericEditField
        UB_delayToLuggageAreaEditFieldLabel  matlab.ui.control.Label
        UB_delayToLuggageAreaEditField  matlab.ui.control.NumericEditField
        LB_luggageDelayEditFieldLabel   matlab.ui.control.Label
        LB_luggageDelayEditField        matlab.ui.control.NumericEditField
        UB_luggageDelayEditFieldLabel   matlab.ui.control.Label
        UB_luggageDelayEditField        matlab.ui.control.NumericEditField
        LB_delayToSecCusEditFieldLabel  matlab.ui.control.Label
        LB_delayToSecCusEditField       matlab.ui.control.NumericEditField
        UB_delayToSecCusEditFieldLabel  matlab.ui.control.Label
        UB_delayToSecCusEditField       matlab.ui.control.NumericEditField
        avg_securityCusServTimeEditFieldLabel  matlab.ui.control.Label
        avg_securityCusServTimeEditField  matlab.ui.control.NumericEditField
        LB_delayToGateEditFieldLabel    matlab.ui.control.Label
        LB_delayToGateEditField         matlab.ui.control.NumericEditField
        UB_delayToGateEditFieldLabel    matlab.ui.control.Label
        UB_delayToGateEditField         matlab.ui.control.NumericEditField
        propability_rejectionEditFieldLabel  matlab.ui.control.Label
        propability_rejectionEditField  matlab.ui.control.NumericEditField
        nQueues_foreignerEditFieldLabel  matlab.ui.control.Label
        nQueues_foreignerEditField      matlab.ui.control.NumericEditField
        nQueues_localEditFieldLabel     matlab.ui.control.Label
        nQueues_localEditField          matlab.ui.control.NumericEditField
        nQueues_secCusEditFieldLabel    matlab.ui.control.Label
        nQueues_secCusEditField         matlab.ui.control.NumericEditField
        Image__ARR1                     matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        ReportDEPTab                    matlab.ui.container.Tab
        statsDEPTable                   matlab.ui.control.Table
        statsDEPTable_plane             matlab.ui.control.Table
        DayEditFieldLabel               matlab.ui.control.Label
        DayEditField                    matlab.ui.control.NumericEditField
        PlanebaseTimeDropDownLabel      matlab.ui.control.Label
        PlanebaseTimeDropDown           matlab.ui.control.DropDown
        CompanyDropDownLabel            matlab.ui.control.Label
        CompanyDropDown                 matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        ModelTab                        matlab.ui.container.Tab
        Image_DEP2                      matlab.ui.control.Image
        PassengersdataTab               matlab.ui.container.Tab
        passengersTable                 matlab.ui.control.Table
        PassengerstimingsTab            matlab.ui.container.Tab
        passengersTimingTable           matlab.ui.control.Table
        companyNameLabel                matlab.ui.control.Label
        ARRButton                       matlab.ui.control.Button
        BacktoconfigButton              matlab.ui.control.Button
        statsDEPTable_2                 matlab.ui.control.Table
        statsDEPTable_plane_2           matlab.ui.control.Table
        ReportARRTab                    matlab.ui.container.Tab
        statsARRTable                   matlab.ui.control.Table
        statsARRTable_plane             matlab.ui.control.Table
        TabGroup3                       matlab.ui.container.TabGroup
        ModelTab_2                      matlab.ui.container.Tab
        Image_ARR2                      matlab.ui.control.Image
        PassengersdataTab_2             matlab.ui.container.Tab
        passengersTable_2               matlab.ui.control.Table
        PassengerstimingsTab_2          matlab.ui.container.Tab
        passengersTimingTable_2         matlab.ui.control.Table
        DayEditField_2Label             matlab.ui.control.Label
        DayEditField_2                  matlab.ui.control.NumericEditField
        PlanebaseTimeDropDown_2Label    matlab.ui.control.Label
        PlanebaseTimeDropDown_2         matlab.ui.control.DropDown
        CompanyDropDown_2Label          matlab.ui.control.Label
        CompanyDropDown_2               matlab.ui.control.DropDown
        DEPButton                       matlab.ui.control.Button
        BacktoconfigButton_2            matlab.ui.control.Button
        nRejectedLabel                  matlab.ui.control.Label
        nRejectedLabel_2                matlab.ui.control.Label
    end

    properties (Access = public)
        simulationReport
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.DayEditField.Limits = [0, app.numberofdaysEditField.Value];
            app.DayEditField_2.Limits = [0, app.numberofdaysEditField.Value];
            app.CompanyDropDown.Items = app.CompaniesnamesTextArea.Value;
            app.CompanyDropDown_2.Items = app.CompaniesnamesTextArea.Value;
            app.passengersTable.RowName = 1:100;
            app.passengersTable_2.RowName = 1:100;
            app.passengersTimingTable.RowName = 1:100;
            app.passengersTimingTable_2.RowName = 1:100;
            
            configDEP = [app.vip_countEditField.Value, app.propability_nLuggageEditField.Value, app.avg_interArrivalTimeEditField.Value, app.avg_counterServTime_LuggageEditField.Value, app.avg_counterServTime_nLuggageEditField.Value, app.LB_securityDelayEditField.Value, app.UB_securityDelayEditField.Value, app.avg_securityServTimeEditField.Value, app.LB_delayToFirstGateEditField.Value, app.UB_delayToFirstGateEditField.Value, app.nQueues_eno_luggageEditField.Value, app.nQueues_eno_nLuggageEditField.Value, app.nQueues_VIPEditField.Value, app.nQueues_securityEditField.Value];
            configARR = [app.propability_foreignerEditField.Value, app.propability_nLuggageEditField_2.Value, app.LB_interArrivalTimeEditField.Value, app.UB_interarrivalTimeEditField.Value, app.LB_delayToVisaClrEditField.Value, app.UB_delayToVisaClrEditField.Value, app.avg_visaClrServTimeEditField.Value, app.LB_delayToLuggageAreaEditField.Value, app.UB_delayToLuggageAreaEditField.Value, app.LB_luggageDelayEditField.Value, app.UB_luggageDelayEditField.Value, app.LB_delayToSecCusEditField.Value, app.UB_delayToSecCusEditField.Value, app.avg_securityCusServTimeEditField.Value, app.LB_delayToGateEditField.Value, app.UB_delayToGateEditField.Value, app.propability_rejectionEditField.Value, app.nQueues_foreignerEditField.Value, app.nQueues_localEditField.Value, app.nQueues_secCusEditField.Value];
            
            app.simulationReport = GENERAL.simulateAirport(app.numberofdaysEditField.Value, [app.CompaniesnamesTextArea.Value], configDEP, configARR);
            
            x = 1;
            for i = 1:app.nQueues_eno_luggageEditField.Value
                app.statsDEPTable.ColumnName{x} = "eco_luggage " + i;
                x = x + 1;
            end
            for i = 1:app.nQueues_eno_nLuggageEditField.Value
                app.statsDEPTable.ColumnName{x} = "eco_nLuggage " + i;
                x = x + 1;
            end
            for i = 1:app.nQueues_VIPEditField.Value
                app.statsDEPTable.ColumnName{x} = "VIP " + i;
                x = x + 1;
            end
            for i = 1:app.nQueues_securityEditField.Value
                app.statsDEPTable.ColumnName{x} = "security " + i;
                x = x + 1;
            end

            x = 1;
            for i = 1:app.nQueues_foreignerEditField.Value
                app.statsARRTable.ColumnName{x} = "visaClr_foreign " + i;
                x = x + 1;
            end
            for i = 1:app.nQueues_localEditField.Value
                app.statsARRTable.ColumnName{x} = "visaClr_local " + i;
                x = x + 1;
            end
            for i = 1:app.nQueues_secCusEditField.Value
                app.statsARRTable.ColumnName{x} = "secCus " + i;
                x = x + 1;
            end

            app.statsDEPTable_plane.ColumnName = app.statsDEPTable.ColumnName;
            app.statsARRTable_plane.ColumnName = app.statsARRTable.ColumnName;
            
            app.companyNameLabel.Text = app.CompaniesnamesTextArea.Value(1);
            app.statsDEPTable.Data = app.simulationReport{1, 2}(:, :, 1);
            app.statsDEPTable_2.Data = app.simulationReport{1, 3}(:, :, 1)';
            app.statsARRTable.Data = app.simulationReport{2, 2};
            app.nRejectedLabel.Text = "%Rejected = " + app.simulationReport{2, 3} + "%";
            
            companyIndex = find(strcmp(app.CompanyDropDown.Items, app.CompanyDropDown.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown.Items, app.PlanebaseTimeDropDown.Value));
            app.statsDEPTable_plane.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 5};
            app.passengersTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 3};
            app.passengersTimingTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 4}(:, [1,2,6,3,4,5,7,8,9,10]);
            app.statsDEPTable_plane_2.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 6}';
                        
            companyIndex = find(strcmp(app.CompanyDropDown_2.Items, app.CompanyDropDown_2.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown_2.Items, app.PlanebaseTimeDropDown_2.Value));
            app.statsARRTable_plane.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 4};
            app.passengersTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 2};
            app.passengersTimingTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 3}(:, [2,3,9,1,4,5,6,7,8,10,11,12,13]);
            app.nRejectedLabel_2.Text = "nRejected = " + app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 5};

            app.TabGroup2.SelectedTab = app.ModelTab;
            app.TabGroup3.SelectedTab = app.ModelTab_2;
            app.TabGroup.SelectedTab = app.ReportDEPTab;
            app.TabGroup1.SelectedTab = app.DEPTab;
        end

        % Value changed function: CompanyDropDown
        function CompanyDropDownValueChanged(app, event)
            companyIndex = find(strcmp(app.CompanyDropDown.Items, app.CompanyDropDown.Value));
            
            app.companyNameLabel.Text = app.CompaniesnamesTextArea.Value(companyIndex);
            app.statsDEPTable.Data = app.simulationReport{1,2}(:, :, companyIndex);
            app.statsDEPTable_2.Data = app.simulationReport{1, 3}(:, :, companyIndex)';
            
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown.Items, app.PlanebaseTimeDropDown.Value));
            app.statsDEPTable_plane.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 5};
            app.passengersTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 3};
            app.passengersTimingTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 4}(:, [1,2,6,3,4,5,7,8,9,10]);
            app.statsDEPTable_plane_2.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 6}';
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
            companyIndex = find(strcmp(app.CompanyDropDown.Items, app.CompanyDropDown.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown.Items, app.PlanebaseTimeDropDown.Value));
            app.statsDEPTable_plane.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 5};
            app.passengersTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 3};
            app.passengersTimingTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 4}(:, [1,2,6,3,4,5,7,8,9,10]);
            app.statsDEPTable_plane_2.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 6}';
        end

        % Value changed function: PlanebaseTimeDropDown
        function PlanebaseTimeDropDownValueChanged(app, event)
            companyIndex = find(strcmp(app.CompanyDropDown.Items, app.CompanyDropDown.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown.Items, app.PlanebaseTimeDropDown.Value));
            app.statsDEPTable_plane.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 5};
            app.passengersTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 3};
            app.passengersTimingTable.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 4}(:, [1,2,6,3,4,5,7,8,9,10]);
            app.statsDEPTable_plane_2.Data = app.simulationReport{1, 1}{app.DayEditField.Value, 1}{companyIndex, 2}{planeIndex, 6}';
        end

        % Callback function
        function nextcompanyButtonPushed(app, event)
            app.company = mod(app.company, length(app.CompaniesnamesTextArea.Value)) + 1;
            app.companyNameLabel.Text = app.CompaniesnamesTextArea.Value(app.company);
            app.statsDEPTable.Data = app.simulationReport{1,2}(:, :, app.company);
            app.statsDEPTable_2.Data = app.simulationReport{1, 3}(:, :, app.company)';
        end

        % Value changed function: CompanyDropDown_2
        function CompanyDropDown_2ValueChanged(app, event)
            companyIndex = find(strcmp(app.CompanyDropDown_2.Items, app.CompanyDropDown_2.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown_2.Items, app.PlanebaseTimeDropDown_2.Value));
            app.statsARRTable_plane.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 4};
            app.passengersTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 2};
            app.passengersTimingTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 3}(:, [2,3,9,1,4,5,6,7,8,10,11,12,13]);
            app.nRejectedLabel_2.Text = "nRejected = " + app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 5};
        end

        % Value changed function: DayEditField_2
        function DayEditField_2ValueChanged(app, event)
            companyIndex = find(strcmp(app.CompanyDropDown_2.Items, app.CompanyDropDown_2.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown_2.Items, app.PlanebaseTimeDropDown_2.Value));
            app.statsARRTable_plane.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 4};
            app.passengersTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 2};
            app.passengersTimingTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 3}(:, [2,3,9,1,4,5,6,7,8,10,11,12,13]);
            app.nRejectedLabel_2.Text = "nRejected = " + app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 5};
        end

        % Value changed function: PlanebaseTimeDropDown_2
        function PlanebaseTimeDropDown_2ValueChanged(app, event)
            companyIndex = find(strcmp(app.CompanyDropDown_2.Items, app.CompanyDropDown_2.Value));
            planeIndex = find(strcmp(app.PlanebaseTimeDropDown_2.Items, app.PlanebaseTimeDropDown_2.Value));
            app.statsARRTable_plane.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 4};
            app.passengersTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 2};
            app.passengersTimingTable_2.Data = app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 3}(:, [2,3,9,1,4,5,6,7,8,10,11,12,13]);
            app.nRejectedLabel_2.Text = "nRejected = " + app.simulationReport{2, 1}{app.DayEditField_2.Value, 1}{companyIndex, 2}{planeIndex, 5};
        end

        % Button pushed function: BacktoconfigButton
        function BacktoconfigButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.configTab;
        end

        % Button pushed function: BacktoconfigButton_2
        function BacktoconfigButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.configTab;
        end

        % Button pushed function: ARRButton
        function ARRButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ReportARRTab;
        end

        % Button pushed function: DEPButton
        function DEPButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ReportDEPTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.configTab = uitab(app.TabGroup);
            app.configTab.Title = 'config';
            app.numberofdaysEditFieldLabel = uilabel(app.configTab);
            app.numberofdaysEditFieldLabel.Text = 'number of days';
            app.numberofdaysEditField = uieditfield(app.configTab, 'numeric');
            app.numberofdaysEditField.Value = 30;
            app.CompaniesnamesTextAreaLabel = uilabel(app.configTab);
            app.CompaniesnamesTextAreaLabel.Text = 'Companies names';
            app.CompaniesnamesTextArea = uitextarea(app.configTab);
            app.CompaniesnamesTextArea.Value = {'Egyptair'; 'Japan Airlines'};
            app.SimulateButton = uibutton(app.configTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TabGroup1 = uitabgroup(app.configTab);
            app.DEPTab = uitab(app.TabGroup1);
            app.DEPTab.Title = 'DEP';
            app.DEPCONFIGSLabel = uilabel(app.DEPTab);
            app.DEPCONFIGSLabel.Text = 'DEP CONFIGS';
            app.vip_countEditFieldLabel = uilabel(app.DEPTab);
            app.vip_countEditFieldLabel.Text = 'vip_count';
            app.vip_countEditField = uieditfield(app.DEPTab, 'numeric');
            app.vip_countEditField.Value = 20;
            app.propability_nLuggageEditFieldLabel = uilabel(app.DEPTab);
            app.propability_nLuggageEditFieldLabel.Text = 'propability_nLuggage';
            app.propability_nLuggageEditField = uieditfield(app.DEPTab, 'numeric');
            app.propability_nLuggageEditField.Value = 0.2;
            app.avg_interArrivalTimeEditFieldLabel = uilabel(app.DEPTab);
            app.avg_interArrivalTimeEditFieldLabel.Text = 'avg_interArrivalTime';
            app.avg_interArrivalTimeEditField = uieditfield(app.DEPTab, 'numeric');
            app.avg_interArrivalTimeEditField.Value = 2.1;
            app.avg_counterServTime_LuggageEditFieldLabel = uilabel(app.DEPTab);
            app.avg_counterServTime_LuggageEditFieldLabel.Text = 'avg_counterServTime_Luggage';
            app.avg_counterServTime_LuggageEditField = uieditfield(app.DEPTab, 'numeric');
            app.avg_counterServTime_LuggageEditField.Value = 3;
            app.avg_counterServTime_nLuggageEditFieldLabel = uilabel(app.DEPTab);
            app.avg_counterServTime_nLuggageEditFieldLabel.Text = 'avg_counterServTime_nLuggage';
            app.avg_counterServTime_nLuggageEditField = uieditfield(app.DEPTab, 'numeric');
            app.avg_counterServTime_nLuggageEditField.Value = 2;
            app.LB_securityDelayEditFieldLabel = uilabel(app.DEPTab);
            app.LB_securityDelayEditFieldLabel.Text = 'LB_securityDelay';
            app.LB_securityDelayEditField = uieditfield(app.DEPTab, 'numeric');
            app.LB_securityDelayEditField.Value = 5;
            app.UB_securityDelayEditFieldLabel = uilabel(app.DEPTab);
            app.UB_securityDelayEditFieldLabel.Text = 'UB_securityDelay';
            app.UB_securityDelayEditField = uieditfield(app.DEPTab, 'numeric');
            app.UB_securityDelayEditField.Value = 15;
            app.avg_securityServTimeEditFieldLabel = uilabel(app.DEPTab);
            app.avg_securityServTimeEditFieldLabel.Text = 'avg_securityServTime';
            app.avg_securityServTimeEditField = uieditfield(app.DEPTab, 'numeric');
            app.avg_securityServTimeEditField.Value = 2;
            app.LB_delayToFirstGateEditFieldLabel = uilabel(app.DEPTab);
            app.LB_delayToFirstGateEditFieldLabel.Text = 'LB_delayToFirstGate';
            app.LB_delayToFirstGateEditField = uieditfield(app.DEPTab, 'numeric');
            app.UB_delayToFirstGateEditFieldLabel = uilabel(app.DEPTab);
            app.UB_delayToFirstGateEditFieldLabel.Text = 'UB_delayToFirstGate';
            app.UB_delayToFirstGateEditField = uieditfield(app.DEPTab, 'numeric');
            app.UB_delayToFirstGateEditField.Value = 5;
            app.nQueues_eno_luggageEditFieldLabel = uilabel(app.DEPTab);
            app.nQueues_eno_luggageEditFieldLabel.Text = 'nQueues_eno_luggage';
            app.nQueues_eno_luggageEditField = uieditfield(app.DEPTab, 'numeric');
            app.nQueues_eno_luggageEditField.Value = 1;
            app.nQueues_eno_nLuggageEditFieldLabel = uilabel(app.DEPTab);
            app.nQueues_eno_nLuggageEditFieldLabel.Text = 'nQueues_eno_nLuggage';
            app.nQueues_eno_nLuggageEditField = uieditfield(app.DEPTab, 'numeric');
            app.nQueues_eno_nLuggageEditField.Value = 1;
            app.nQueues_VIPEditFieldLabel = uilabel(app.DEPTab);
            app.nQueues_VIPEditFieldLabel.Text = 'nQueues_VIP';
            app.nQueues_VIPEditField = uieditfield(app.DEPTab, 'numeric');
            app.nQueues_VIPEditField.Value = 1;
            app.nQueues_securityEditFieldLabel = uilabel(app.DEPTab);
            app.nQueues_securityEditFieldLabel.Text = 'nQueues_security';
            app.nQueues_securityEditField = uieditfield(app.DEPTab, 'numeric');
            app.nQueues_securityEditField.Value = 1;
            app.Image_DEP1 = uiimage(app.DEPTab);
            app.Image_DEP1.ImageSource = 'DEP1.svg';
            app.Label = uilabel(app.DEPTab);
            app.Label.Text = '________________________________________________________________________________________________________________________________';
            app.ARRTab = uitab(app.TabGroup1);
            app.ARRTab.Title = 'ARR';
            app.ARRCONFIGSLabel = uilabel(app.ARRTab);
            app.ARRCONFIGSLabel.Text = 'ARR CONFIGS';
            app.propability_foreignerEditFieldLabel = uilabel(app.ARRTab);
            app.propability_foreignerEditFieldLabel.Text = 'propability_foreigner';
            app.propability_foreignerEditField = uieditfield(app.ARRTab, 'numeric');
            app.propability_foreignerEditField.Value = 0.2;
            app.propability_nLuggageEditField_2Label = uilabel(app.ARRTab);
            app.propability_nLuggageEditField_2Label.Text = 'propability_nLuggage';
            app.propability_nLuggageEditField_2 = uieditfield(app.ARRTab, 'numeric');
            app.propability_nLuggageEditField_2.Value = 0.2;
            app.LB_interArrivalTimeEditFieldLabel = uilabel(app.ARRTab);
            app.LB_interArrivalTimeEditFieldLabel.Text = 'LB_interArrivalTime';
            app.LB_interArrivalTimeEditField = uieditfield(app.ARRTab, 'numeric');
            app.LB_interArrivalTimeEditField.Value = 0.08333;
            app.UB_interarrivalTimeEditFieldLabel = uilabel(app.ARRTab);
            app.UB_interarrivalTimeEditFieldLabel.Text = 'UB_interarrivalTime';
            app.UB_interarrivalTimeEditField = uieditfield(app.ARRTab, 'numeric');
            app.UB_interarrivalTimeEditField.Value = 0.08333;
            app.LB_delayToVisaClrEditFieldLabel = uilabel(app.ARRTab);
            app.LB_delayToVisaClrEditFieldLabel.Text = 'LB_delayToVisaClr';
            app.LB_delayToVisaClrEditField = uieditfield(app.ARRTab, 'numeric');
            app.LB_delayToVisaClrEditField.Value = 3;
            app.UB_delayToVisaClrEditFieldLabel = uilabel(app.ARRTab);
            app.UB_delayToVisaClrEditFieldLabel.Text = 'UB_delayToVisaClr';
            app.UB_delayToVisaClrEditField = uieditfield(app.ARRTab, 'numeric');
            app.UB_delayToVisaClrEditField.Value = 8;
            app.avg_visaClrServTimeEditFieldLabel = uilabel(app.ARRTab);
            app.avg_visaClrServTimeEditFieldLabel.Text = 'avg_visaClrServTime';
            app.avg_visaClrServTimeEditField = uieditfield(app.ARRTab, 'numeric');
            app.avg_visaClrServTimeEditField.Value = 1;
            app.LB_delayToLuggageAreaEditFieldLabel = uilabel(app.ARRTab);
            app.LB_delayToLuggageAreaEditFieldLabel.Text = 'LB_delayToLuggageArea';
            app.LB_delayToLuggageAreaEditField = uieditfield(app.ARRTab, 'numeric');
            app.LB_delayToLuggageAreaEditField.Value = 5;
            app.UB_delayToLuggageAreaEditFieldLabel = uilabel(app.ARRTab);
            app.UB_delayToLuggageAreaEditFieldLabel.Text = 'UB_delayToLuggageArea';
            app.UB_delayToLuggageAreaEditField = uieditfield(app.ARRTab, 'numeric');
            app.UB_delayToLuggageAreaEditField.Value = 10;
            app.LB_luggageDelayEditFieldLabel = uilabel(app.ARRTab);
            app.LB_luggageDelayEditFieldLabel.Text = 'LB_luggageDelay';
            app.LB_luggageDelayEditField = uieditfield(app.ARRTab, 'numeric');
            app.LB_luggageDelayEditField.Value = 5;
            app.UB_luggageDelayEditFieldLabel = uilabel(app.ARRTab);
            app.UB_luggageDelayEditFieldLabel.Text = 'UB_luggageDelay';
            app.UB_luggageDelayEditField = uieditfield(app.ARRTab, 'numeric');
            app.UB_luggageDelayEditField.Value = 30;
            app.LB_delayToSecCusEditFieldLabel = uilabel(app.ARRTab);
            app.LB_delayToSecCusEditFieldLabel.Text = 'LB_delayToSecCus';
            app.LB_delayToSecCusEditField = uieditfield(app.ARRTab, 'numeric');
            app.LB_delayToSecCusEditField.Value = 3;
            app.UB_delayToSecCusEditFieldLabel = uilabel(app.ARRTab);
            app.UB_delayToSecCusEditFieldLabel.Text = 'UB_delayToSecCus';
            app.UB_delayToSecCusEditField = uieditfield(app.ARRTab, 'numeric');
            app.UB_delayToSecCusEditField.Value = 8;
            app.avg_securityCusServTimeEditFieldLabel = uilabel(app.ARRTab);
            app.avg_securityCusServTimeEditFieldLabel.Text = 'avg_securityCusServTime';
            app.avg_securityCusServTimeEditField = uieditfield(app.ARRTab, 'numeric');
            app.avg_securityCusServTimeEditField.Value = 2;
            app.LB_delayToGateEditFieldLabel = uilabel(app.ARRTab);
            app.LB_delayToGateEditFieldLabel.Text = 'LB_delayToGate';
            app.LB_delayToGateEditField = uieditfield(app.ARRTab, 'numeric');
            app.LB_delayToGateEditField.Value = 5;
            app.UB_delayToGateEditFieldLabel = uilabel(app.ARRTab);
            app.UB_delayToGateEditFieldLabel.Text = 'UB_delayToGate';
            app.UB_delayToGateEditField = uieditfield(app.ARRTab, 'numeric');
            app.UB_delayToGateEditField.Value = 10;
            app.propability_rejectionEditFieldLabel = uilabel(app.ARRTab);
            app.propability_rejectionEditFieldLabel.Text = 'propability_rejection';
            app.propability_rejectionEditField = uieditfield(app.ARRTab, 'numeric');
            app.propability_rejectionEditField.Value = 0.01;
            app.nQueues_foreignerEditFieldLabel = uilabel(app.ARRTab);
            app.nQueues_foreignerEditFieldLabel.Text = 'nQueues_foreigner';
            app.nQueues_foreignerEditField = uieditfield(app.ARRTab, 'numeric');
            app.nQueues_foreignerEditField.Value = 4;
            app.nQueues_localEditFieldLabel = uilabel(app.ARRTab);
            app.nQueues_localEditFieldLabel.Text = 'nQueues_local';
            app.nQueues_localEditField = uieditfield(app.ARRTab, 'numeric');
            app.nQueues_localEditField.Value = 2;
            app.nQueues_secCusEditFieldLabel = uilabel(app.ARRTab);
            app.nQueues_secCusEditFieldLabel.Text = 'nQueues_secCus';
            app.nQueues_secCusEditField = uieditfield(app.ARRTab, 'numeric');
            app.nQueues_secCusEditField.Value = 4;
            app.Image__ARR1 = uiimage(app.ARRTab);
            app.Image__ARR1.ImageSource = 'ARR1.svg';
            app.Label_2 = uilabel(app.ARRTab);
            app.Label_2.Text = '________________________________________________________________________________________________________________________________';
            app.ReportDEPTab = uitab(app.TabGroup);
            app.ReportDEPTab.Title = 'ReportDEP';
            app.statsDEPTable = uitable(app.ReportDEPTab);
            app.statsDEPTable.ColumnName = {''};
            app.statsDEPTable.RowName = {'Wq'; 'Ws'; 'Lq'; 'idleness%'};
            app.statsDEPTable_plane = uitable(app.ReportDEPTab);
            app.statsDEPTable_plane.ColumnName = {''};
            app.statsDEPTable_plane.RowName = {'Wq'; 'Ws'; 'Lq'; 'idleness%'};
            app.DayEditFieldLabel = uilabel(app.ReportDEPTab);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.ReportDEPTab, 'numeric');
            app.DayEditField.Limits = [1 Inf];
            app.DayEditField.RoundFractionalValues = 'on';
            app.DayEditField.ValueDisplayFormat = '%.0f';
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.DayEditField.Value = 1;
            app.PlanebaseTimeDropDownLabel = uilabel(app.ReportDEPTab);
            app.PlanebaseTimeDropDownLabel.Text = 'Plane baseTime';
            app.PlanebaseTimeDropDown = uidropdown(app.ReportDEPTab);
            app.PlanebaseTimeDropDown.Items = {'00:00', '04:00', '08:00', '12:00', '16:00', '20:00'};
            app.PlanebaseTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlanebaseTimeDropDownValueChanged, true);
            app.PlanebaseTimeDropDown.Value = '00:00';
            app.CompanyDropDownLabel = uilabel(app.ReportDEPTab);
            app.CompanyDropDownLabel.Text = 'Company';
            app.CompanyDropDown = uidropdown(app.ReportDEPTab);
            app.CompanyDropDown.ValueChangedFcn = createCallbackFcn(app, @CompanyDropDownValueChanged, true);
            app.TabGroup2 = uitabgroup(app.ReportDEPTab);
            app.ModelTab = uitab(app.TabGroup2);
            app.ModelTab.Title = 'Model';
            app.Image_DEP2 = uiimage(app.ModelTab);
            app.Image_DEP2.ImageSource = 'DEP2.svg';
            app.PassengersdataTab = uitab(app.TabGroup2);
            app.PassengersdataTab.Title = 'Passengers data';
            app.passengersTable = uitable(app.PassengersdataTab);
            app.passengersTable.ColumnName = {'isVIP'; 'luggage'; 'interarrivalTime'; 'counterServTime'; 'delayToSecurity'; 'securityServTime'; 'delayToGate'};
            app.passengersTable.RowName = {};
            app.PassengerstimingsTab = uitab(app.TabGroup2);
            app.PassengerstimingsTab.Title = 'Passengers timings';
            app.passengersTimingTable = uitable(app.PassengerstimingsTab);
            app.passengersTimingTable.ColumnName = {'counterType'; 'counterQ#'; 'securityQ#'; 'start'; 'A'; 'B'; 'C'; 'D'; 'E'; 'end'};
            app.passengersTimingTable.RowName = {};
            app.companyNameLabel = uilabel(app.ReportDEPTab);
            app.companyNameLabel.Text = 'companyName';
            app.ARRButton = uibutton(app.ReportDEPTab, 'push');
            app.ARRButton.ButtonPushedFcn = createCallbackFcn(app, @ARRButtonPushed, true);
            app.ARRButton.Text = 'ARR';
            app.BacktoconfigButton = uibutton(app.ReportDEPTab, 'push');
            app.BacktoconfigButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoconfigButtonPushed, true);
            app.BacktoconfigButton.Text = 'Back to config';
            app.statsDEPTable_2 = uitable(app.ReportDEPTab);
            app.statsDEPTable_2.ColumnName = {'otherStats'};
            app.statsDEPTable_2.RowName = {'%Excluded_counter'; '%Excluded_gate'};
            app.statsDEPTable_plane_2 = uitable(app.ReportDEPTab);
            app.statsDEPTable_plane_2.ColumnName = {'otherStats'};
            app.statsDEPTable_plane_2.RowName = {'nExcluded_counter'; 'nExcluded_gate'};
            app.ReportARRTab = uitab(app.TabGroup);
            app.ReportARRTab.Title = 'ReportARR';
            app.statsARRTable = uitable(app.ReportARRTab);
            app.statsARRTable.ColumnName = {''};
            app.statsARRTable.RowName = {'Wq'; 'Ws'; 'Lq'; 'idleness%'};
            app.statsARRTable_plane = uitable(app.ReportARRTab);
            app.statsARRTable_plane.ColumnName = {''};
            app.statsARRTable_plane.RowName = {'Wq'; 'Ws'};
            app.TabGroup3 = uitabgroup(app.ReportARRTab);
            app.ModelTab_2 = uitab(app.TabGroup3);
            app.ModelTab_2.Title = 'Model';
            app.Image_ARR2 = uiimage(app.ModelTab_2);
            app.Image_ARR2.ImageSource = 'ARR2.svg';
            app.PassengersdataTab_2 = uitab(app.TabGroup3);
            app.PassengersdataTab_2.Title = 'Passengers data';
            app.passengersTable_2 = uitable(app.PassengersdataTab_2);
            app.passengersTable_2.ColumnName = {'isForeigner'; 'hasLuggage'; 'interarrivalTime'; 'delayToVisaClr'; 'visaClrServTime'; 'delayToLuggageArea'; 'luggageDelay'; 'delayToSecCus'; 'secCusServTime'; 'delayToGate'; 'isRejected'};
            app.passengersTable_2.RowName = {};
            app.PassengerstimingsTab_2 = uitab(app.TabGroup3);
            app.PassengerstimingsTab_2.Title = 'Passengers timings';
            app.passengersTimingTable_2 = uitable(app.PassengerstimingsTab_2);
            app.passengersTimingTable_2.ColumnName = {'visaClrCounterType'; 'visaClrQ#'; 'secCusQ#'; 'start'; 'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'; 'end'};
            app.passengersTimingTable_2.RowName = {};
            app.DayEditField_2Label = uilabel(app.ReportARRTab);
            app.DayEditField_2Label.Text = 'Day';
            app.DayEditField_2 = uieditfield(app.ReportARRTab, 'numeric');
            app.DayEditField_2.Limits = [1 Inf];
            app.DayEditField_2.RoundFractionalValues = 'on';
            app.DayEditField_2.ValueDisplayFormat = '%.0f';
            app.DayEditField_2.ValueChangedFcn = createCallbackFcn(app, @DayEditField_2ValueChanged, true);
            app.DayEditField_2.Value = 1;
            app.PlanebaseTimeDropDown_2Label = uilabel(app.ReportARRTab);
            app.PlanebaseTimeDropDown_2Label.Text = 'Plane baseTime';
            app.PlanebaseTimeDropDown_2 = uidropdown(app.ReportARRTab);
            app.PlanebaseTimeDropDown_2.Items = {'00:00', '04:00', '08:00', '12:00', '16:00', '20:00'};
            app.PlanebaseTimeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PlanebaseTimeDropDown_2ValueChanged, true);
            app.PlanebaseTimeDropDown_2.Value = '00:00';
            app.CompanyDropDown_2Label = uilabel(app.ReportARRTab);
            app.CompanyDropDown_2Label.Text = 'Company';
            app.CompanyDropDown_2 = uidropdown(app.ReportARRTab);
            app.CompanyDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CompanyDropDown_2ValueChanged, true);
            app.DEPButton = uibutton(app.ReportARRTab, 'push');
            app.DEPButton.ButtonPushedFcn = createCallbackFcn(app, @DEPButtonPushed, true);
            app.DEPButton.Text = 'DEP';
            app.BacktoconfigButton_2 = uibutton(app.ReportARRTab, 'push');
            app.BacktoconfigButton_2.ButtonPushedFcn = createCallbackFcn(app, @BacktoconfigButton_2Pushed, true);
            app.BacktoconfigButton_2.Text = 'Back to config';
            app.nRejectedLabel = uilabel(app.ReportARRTab);
            app.nRejectedLabel.Text = 'nRejected';
            app.nRejectedLabel_2 = uilabel(app.ReportARRTab);
            app.nRejectedLabel_2.Text = 'nRejected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end